classdef tanks_modelling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        information                     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        infoTitle                       matlab.ui.control.Label
        creator                         matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        oneTank_ecuations               matlab.ui.control.Button
        twoTanks_ecuations              matlab.ui.control.Button
        Label3                          matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        helpButton                      matlab.ui.control.Button
        oneTank                         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        oneTankTitle                    matlab.ui.control.Label
        AlturadeltanquemEditFieldLabel  matlab.ui.control.Label
        oneTankHeight                   matlab.ui.control.NumericEditField
        oneTankAxes                     matlab.ui.control.UIAxes
        TipodesalidadeflujoSwitch_2Label  matlab.ui.control.Label
        oneTankSwitch                   matlab.ui.control.Switch
        readeltanquem2Label             matlab.ui.control.Label
        oneTankArea                     matlab.ui.control.NumericEditField
        NiveldeltanquemLabel            matlab.ui.control.Label
        oneTankLevel                    matlab.ui.control.NumericEditField
        Flujodeentradam3sLabel          matlab.ui.control.Label
        oneTankFlowFluid                matlab.ui.control.NumericEditField
        TiempodesimulacinsLabel         matlab.ui.control.Label
        oneTankSimTime                  matlab.ui.control.NumericEditField
        oneTankSimButton                matlab.ui.control.Button
        twoTanks                        matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        twoTanksTitle                   matlab.ui.control.Label
        twoTanksTank1                   matlab.ui.control.UIAxes
        twoTanksTank2                   matlab.ui.control.UIAxes
        TipodesalidadeflujoSwitchLabel  matlab.ui.control.Label
        twoTanksSwitch                  matlab.ui.control.Switch
        Alturadetanque1mEditFieldLabel  matlab.ui.control.Label
        twoTanksHeight1                 matlab.ui.control.NumericEditField
        readeltanque1m2EditFieldLabel   matlab.ui.control.Label
        twoTanksArea1                   matlab.ui.control.NumericEditField
        Niveldeltanque1mEditFieldLabel  matlab.ui.control.Label
        twoTanksLevel1                  matlab.ui.control.NumericEditField
        Flujodeentradam3sEditField_3Label  matlab.ui.control.Label
        twoTanksFluidIn                 matlab.ui.control.NumericEditField
        TiempodesimulacinsEditField_2Label  matlab.ui.control.Label
        twoTankSimTime                  matlab.ui.control.NumericEditField
        Alturadetanque2mEditFieldLabel  matlab.ui.control.Label
        twoTanksHeight2                 matlab.ui.control.NumericEditField
        readeltanque2m2EditFieldLabel   matlab.ui.control.Label
        twoTanksArea2                   matlab.ui.control.NumericEditField
        Niveldeltanque2mEditFieldLabel  matlab.ui.control.Label
        twoTanksLevel2                  matlab.ui.control.NumericEditField
        Flujodesalidam3sEditFieldLabel  matlab.ui.control.Label
        twoTanksFluidOut                matlab.ui.control.NumericEditField
        twoTanksSimButton               matlab.ui.control.Button
        twoTanksPID                     matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        twoTanksPIDTItle                matlab.ui.control.Label
        readeltanque1m2EditField_2Label  matlab.ui.control.Label
        twoTanksPIDArea1                matlab.ui.control.NumericEditField
        twoTanksPIDAxes                 matlab.ui.control.UIAxes
        readeltanque2m2Label            matlab.ui.control.Label
        twoTanksPIDArea2                matlab.ui.control.NumericEditField
        Niveldeltanque1mLabel           matlab.ui.control.Label
        twoTanksPIDLevel1               matlab.ui.control.NumericEditField
        Setpointtanque2mLabel           matlab.ui.control.Label
        twoTanksPIDSetpoint             matlab.ui.control.NumericEditField
        Flujodeentradam3sLabel_2        matlab.ui.control.Label
        twoTanksPIDFluidIn              matlab.ui.control.NumericEditField
        Flujodesalidam3sLabel           matlab.ui.control.Label
        twoTanksPIDFluidOut             matlab.ui.control.NumericEditField
        Alturadetanque2mLabel           matlab.ui.control.Label
        twoTanksPIDHeight               matlab.ui.control.NumericEditField
        KpLabel                         matlab.ui.control.Label
        twoTanksPIDKp                   matlab.ui.control.NumericEditField
        KiLabel                         matlab.ui.control.Label
        twoTanksPIDKi                   matlab.ui.control.NumericEditField
        KdEditFieldLabel_9              matlab.ui.control.Label
        twoTanksPIDKd                   matlab.ui.control.NumericEditField
        TiempodesimulacinsLabel_2       matlab.ui.control.Label
        twoTanksPIDSimTime              matlab.ui.control.NumericEditField
        RetrasoenaperturasLabel         matlab.ui.control.Label
        twoTanksPIDDelay                matlab.ui.control.NumericEditField
        SIMULARButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: oneTankSimButton
        function oneTankSimButtonPushed(app, event)
            area = app.oneTankArea.Value;
            height = app.oneTankHeight.Value;
            level = app.oneTankLevel.Value;
            qin  = app.oneTankFlowFluid.Value;
            time = app.oneTankSimTime.Value;
            resistance = level/qin;            
            tao = area * resistance;
            type = app.oneTankSwitch.Value;
            assignin('base','area',area);
            assignin('base','level',level);
            assignin('base','qin',qin);
            assignin('base','resistance',resistance);
            assignin('base','time',time);
            assignin('base','tao',tao);
            if type == "Turbulento"
                simulation = sim('one_tank_turbulent');
                time = simulation.time;
                data = simulation.data;
            else
                simulation = sim('one_tank_laminar');
                time = simulation.time;
                data = simulation.data;
            end
            app.oneTankAxes.YLim = [0 height];
            plot(app.oneTankAxes, time, data);
        end

        % Button pushed function: twoTanksSimButton
        function twoTanksSimButtonPushed(app, event)
            area1 = app.twoTanksArea1.Value;
            area2 = app.twoTanksArea2.Value;
            height1 = app.twoTanksHeight1.Value;
            height2 = app.twoTanksHeight2.Value;
            level1 = app.twoTanksLevel1.Value;
            level2 = app.twoTanksLevel2.Value;
            qin = app.twoTanksFluidIn.Value;
            qout = app.twoTanksFluidOut.Value;
            time = app.twoTankSimTime.Value;
            resistance1 = (level1 - level2) / qin;
            resistance2 = level2 / qout;
            tao1 = area1 * resistance1;
            tao2 = area2 * resistance2;
            type = app.twoTanksSwitch.Value;
            assignin('base','area1',area1);
            assignin('base','area2',area2);
            assignin('base','level1',level1);
            assignin('base','level2',level2);
            assignin('base','qin',qin);
            assignin('base','qout',qout);
            assignin('base','resistance1',resistance1);
            assignin('base','resistance2',resistance2);
            assignin('base','tao1',tao1);
            assignin('base','tao2',tao2);
            assignin('base','time',time);
            if type == "Turbulento"
                simulation = sim('two_coupled_tanks_laminar');
                time = simulation.time;
                data1 = simulation.data1;
                data2 = simulation.data2;
            else
                simulation = sim('two_coupled_tanks_laminar');
                time = simulation.time;
                data1 = simulation.data1;
                data2 = simulation.data2;
            end
            app.twoTanksTank1.YLim = [0 height1];
            plot(app.twoTanksTank1, time, data1);
            app.twoTanksTank2.YLim = [0 height2];
            plot(app.twoTanksTank2, time, data2);
            
            
        end

        % Button pushed function: SIMULARButton
        function twoTanksPIDSimButtonPushed(app, event)
            area1 = app.twoTanksPIDArea1.Value;
            area2 = app.twoTanksPIDArea2.Value;
            height = app.twoTanksPIDHeight.Value;
            level1 = app.twoTanksPIDLevel1.Value;
            setpoint = app.twoTanksPIDSetpoint.Value;
            qin = app.twoTanksPIDFluidIn.Value;
            qout = app.twoTanksPIDFluidOut.Value;
            
            kp = app.twoTanksPIDKp.Value;
            ki = app.twoTanksPIDKi.Value;
            kd = app.twoTanksPIDKd.Value;
            time = app.twoTanksPIDSimTime.Value;
            delay = app.twoTanksPIDDelay.Value;
            
            resistance1 = level1 - setpoint / qin;
            resistance2 = setpoint / qout;
            tao1 = area1 * resistance1;
            tao2 = area2 * resistance2;
            
            assignin('base','area1',area1);
            assignin('base','area2',area2);
            assignin('base','level1',level1);
            assignin('base','setpoint',setpoint);
            assignin('base','qin',qin);
            assignin('base','qout',qout);
            assignin('base','resistance1',resistance1);
            assignin('base','resistance2',resistance2);
            assignin('base','tao1',tao1);
            assignin('base','tao2',tao2);
            assignin('base','time',time);
            assignin('base','kp',kp);
            assignin('base','ki',ki);
            assignin('base','kd',kd);
            assignin('base','delay',delay);
            
            simulation = sim('two_coupled_tanks_PID');
            time = simulation.time;
           
            data2 = simulation.data2;
           
            app.twoTanksPIDAxes.YLim = [0 height];
            plot(app.twoTanksPIDAxes, time, data2);
        end

        % Button pushed function: helpButton
        function helpButtonPushed(app, event)
            open("Uso de interfaz gráfica para modelamiento de tanques acoplados.pdf")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.information = uitab(app.TabGroup);
            app.information.Title = 'Información';
            app.GridLayout = uigridlayout(app.information);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Tank Filling Modeling es una aplicación creada con la finalidad de que los estudiantes que encuentren cursando las materias Sistemas de control e Instrumentación industrial'; 'puedan interactuar en ella, y desarrollar practicas con respecto al llenado de diversos modelos de tanques acoplados en serie.'; ''};
            app.infoTitle = uilabel(app.GridLayout);
            app.infoTitle.Text = 'TANK FILLING MODELING';
            app.creator = uilabel(app.GridLayout);
            app.creator.Text = {'Creado por @jilverprivera | Universidad de Investigación y Desarrollo - UDI'; '2022-1'; ''};
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = 'En la parte superior izquierda podrá observar los distintos ejercicios propuestos para el desarrollo de prácticas académicas.';
            app.oneTank_ecuations = uibutton(app.GridLayout, 'push');
            app.oneTank_ecuations.Text = 'Ecuaciones de un tanque';
            app.twoTanks_ecuations = uibutton(app.GridLayout, 'push');
            app.twoTanks_ecuations.Text = 'Ecuaciones de dos tanques';
            app.Label3 = uilabel(app.GridLayout);
            app.Label3.Text = 'Para ver las ecuaciones implementadas en cada uno de los diferentes tanques, de clic en los botones, estos abrirán un archivo PDF con las mismas.';
            app.Label4 = uilabel(app.GridLayout);
            app.Label4.Text = 'Para ver el manual de uso de la interfaz gráfica de clic en el botón "ayuda" y un archivo PDF se abrirá mostrando así el instructivo.';
            app.helpButton = uibutton(app.GridLayout, 'push');
            app.helpButton.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.helpButton.Text = 'Ayuda';
            app.oneTank = uitab(app.TabGroup);
            app.oneTank.Title = '1 Tanque';
            app.GridLayout2 = uigridlayout(app.oneTank);
            app.oneTankTitle = uilabel(app.GridLayout2);
            app.oneTankTitle.Text = 'MODELAMIENTO MATEMÁTICO PARA LLENADO DE UN TANQUE';
            app.AlturadeltanquemEditFieldLabel = uilabel(app.GridLayout2);
            app.AlturadeltanquemEditFieldLabel.Text = 'Altura del tanque (m)';
            app.oneTankHeight = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankHeight.Limits = [0 Inf];
            app.oneTankAxes = uiaxes(app.GridLayout2);
            title(app.oneTankAxes, 'Llenado del tanque')
            xlabel(app.oneTankAxes, 'Tiempo (s)')
            ylabel(app.oneTankAxes, 'Altura (m)')
            app.TipodesalidadeflujoSwitch_2Label = uilabel(app.GridLayout2);
            app.TipodesalidadeflujoSwitch_2Label.Text = 'Tipo de salida de flujo';
            app.oneTankSwitch = uiswitch(app.GridLayout2, 'slider');
            app.oneTankSwitch.Items = {'Laminar', 'Turbulento'};
            app.oneTankSwitch.Value = 'Laminar';
            app.readeltanquem2Label = uilabel(app.GridLayout2);
            app.readeltanquem2Label.Text = 'Área del tanque (m^2)';
            app.oneTankArea = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankArea.Limits = [0 Inf];
            app.NiveldeltanquemLabel = uilabel(app.GridLayout2);
            app.NiveldeltanquemLabel.Text = 'Nivel del tanque (m)';
            app.oneTankLevel = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankLevel.Limits = [0 Inf];
            app.Flujodeentradam3sLabel = uilabel(app.GridLayout2);
            app.Flujodeentradam3sLabel.Text = 'Flujo de entrada (m^3/s)';
            app.oneTankFlowFluid = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankFlowFluid.Limits = [0 Inf];
            app.TiempodesimulacinsLabel = uilabel(app.GridLayout2);
            app.TiempodesimulacinsLabel.Text = 'Tiempo de simulación (s)';
            app.oneTankSimTime = uieditfield(app.GridLayout2, 'numeric');
            app.oneTankSimTime.Limits = [0 Inf];
            app.oneTankSimButton = uibutton(app.GridLayout2, 'push');
            app.oneTankSimButton.ButtonPushedFcn = createCallbackFcn(app, @oneTankSimButtonPushed, true);
            app.oneTankSimButton.Text = 'SIMULAR';
            app.twoTanks = uitab(app.TabGroup);
            app.twoTanks.Title = '2 Tanques';
            app.GridLayout3 = uigridlayout(app.twoTanks);
            app.twoTanksTitle = uilabel(app.GridLayout3);
            app.twoTanksTitle.Text = 'MODELAMIENTO MATEMÁTICO PARA LLENADO DE DOS TANQUE ACOPLADOS EN SERIE';
            app.twoTanksTank1 = uiaxes(app.GridLayout3);
            title(app.twoTanksTank1, 'Llenado de tanque 1')
            xlabel(app.twoTanksTank1, 'Tiempo (s)')
            ylabel(app.twoTanksTank1, 'Altura (m)')
            app.twoTanksTank2 = uiaxes(app.GridLayout3);
            title(app.twoTanksTank2, 'Llenado de tanque 2')
            xlabel(app.twoTanksTank2, 'Tiempo (s)')
            ylabel(app.twoTanksTank2, 'Altura (m)')
            app.TipodesalidadeflujoSwitchLabel = uilabel(app.GridLayout3);
            app.TipodesalidadeflujoSwitchLabel.Text = 'Tipo de salida de flujo';
            app.twoTanksSwitch = uiswitch(app.GridLayout3, 'slider');
            app.twoTanksSwitch.Items = {'Laminar', 'Turbulento'};
            app.twoTanksSwitch.Value = 'Laminar';
            app.Alturadetanque1mEditFieldLabel = uilabel(app.GridLayout3);
            app.Alturadetanque1mEditFieldLabel.Text = 'Altura de tanque 1 (m)';
            app.twoTanksHeight1 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksHeight1.Limits = [0 Inf];
            app.readeltanque1m2EditFieldLabel = uilabel(app.GridLayout3);
            app.readeltanque1m2EditFieldLabel.Text = 'Área del tanque 1 (m^2)';
            app.twoTanksArea1 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksArea1.Limits = [0 Inf];
            app.Niveldeltanque1mEditFieldLabel = uilabel(app.GridLayout3);
            app.Niveldeltanque1mEditFieldLabel.Text = 'Nivel del tanque 1 (m)';
            app.twoTanksLevel1 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksLevel1.Limits = [0 Inf];
            app.Flujodeentradam3sEditField_3Label = uilabel(app.GridLayout3);
            app.Flujodeentradam3sEditField_3Label.Text = 'Flujo de entrada (m^3/s)';
            app.twoTanksFluidIn = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksFluidIn.Limits = [0 Inf];
            app.TiempodesimulacinsEditField_2Label = uilabel(app.GridLayout3);
            app.TiempodesimulacinsEditField_2Label.Text = 'Tiempo de simulación (s)';
            app.twoTankSimTime = uieditfield(app.GridLayout3, 'numeric');
            app.twoTankSimTime.Limits = [0 Inf];
            app.Alturadetanque2mEditFieldLabel = uilabel(app.GridLayout3);
            app.Alturadetanque2mEditFieldLabel.Text = 'Altura de tanque 2 (m)';
            app.twoTanksHeight2 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksHeight2.Limits = [0 Inf];
            app.readeltanque2m2EditFieldLabel = uilabel(app.GridLayout3);
            app.readeltanque2m2EditFieldLabel.Text = 'Área del tanque 2 (m^2)';
            app.twoTanksArea2 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksArea2.Limits = [0 Inf];
            app.Niveldeltanque2mEditFieldLabel = uilabel(app.GridLayout3);
            app.Niveldeltanque2mEditFieldLabel.Text = 'Nivel del tanque 2 (m)';
            app.twoTanksLevel2 = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksLevel2.Limits = [0 Inf];
            app.Flujodesalidam3sEditFieldLabel = uilabel(app.GridLayout3);
            app.Flujodesalidam3sEditFieldLabel.Text = 'Flujo de salida (m^3/s)';
            app.twoTanksFluidOut = uieditfield(app.GridLayout3, 'numeric');
            app.twoTanksFluidOut.Limits = [0 Inf];
            app.twoTanksSimButton = uibutton(app.GridLayout3, 'push');
            app.twoTanksSimButton.ButtonPushedFcn = createCallbackFcn(app, @twoTanksSimButtonPushed, true);
            app.twoTanksSimButton.Text = 'SIMULAR';
            app.twoTanksPID = uitab(app.TabGroup);
            app.twoTanksPID.Title = '2 Tanques [Control PID]';
            app.GridLayout4 = uigridlayout(app.twoTanksPID);
            app.twoTanksPIDTItle = uilabel(app.GridLayout4);
            app.twoTanksPIDTItle.Text = 'MODELAMIENTO MATEMÁTICO PARA LLENADO DE DOS TANQUE ACOPLADOS CON SISTEMA DE CONTROL PID PARA SEGUNDO TANQUE';
            app.readeltanque1m2EditField_2Label = uilabel(app.GridLayout4);
            app.readeltanque1m2EditField_2Label.Text = 'Área del tanque 1 (m^2)';
            app.twoTanksPIDArea1 = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDArea1.Limits = [0 Inf];
            app.twoTanksPIDAxes = uiaxes(app.GridLayout4);
            title(app.twoTanksPIDAxes, 'Llenado de tanque 2')
            xlabel(app.twoTanksPIDAxes, 'Tiempo (s)')
            ylabel(app.twoTanksPIDAxes, 'Altura (m)')
            app.readeltanque2m2Label = uilabel(app.GridLayout4);
            app.readeltanque2m2Label.Text = 'Área del tanque 2 (m^2)';
            app.twoTanksPIDArea2 = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDArea2.Limits = [0 Inf];
            app.Niveldeltanque1mLabel = uilabel(app.GridLayout4);
            app.Niveldeltanque1mLabel.Text = 'Nivel del tanque 1 (m)';
            app.twoTanksPIDLevel1 = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDLevel1.Limits = [0 Inf];
            app.Setpointtanque2mLabel = uilabel(app.GridLayout4);
            app.Setpointtanque2mLabel.Text = 'Setpoint tanque 2 (m)';
            app.twoTanksPIDSetpoint = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDSetpoint.Limits = [0 Inf];
            app.Flujodeentradam3sLabel_2 = uilabel(app.GridLayout4);
            app.Flujodeentradam3sLabel_2.Text = 'Flujo de entrada (m^3/s)';
            app.twoTanksPIDFluidIn = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDFluidIn.Limits = [0 Inf];
            app.Flujodesalidam3sLabel = uilabel(app.GridLayout4);
            app.Flujodesalidam3sLabel.Text = 'Flujo de salida (m^3/s)';
            app.twoTanksPIDFluidOut = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDFluidOut.Limits = [0 Inf];
            app.Alturadetanque2mLabel = uilabel(app.GridLayout4);
            app.Alturadetanque2mLabel.Text = 'Altura de tanque 2 (m)';
            app.twoTanksPIDHeight = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDHeight.Limits = [0 Inf];
            app.KpLabel = uilabel(app.GridLayout4);
            app.KpLabel.Text = 'Kp';
            app.twoTanksPIDKp = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDKp.Limits = [0 Inf];
            app.KiLabel = uilabel(app.GridLayout4);
            app.KiLabel.Text = 'Ki';
            app.twoTanksPIDKi = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDKi.Limits = [0 Inf];
            app.KdEditFieldLabel_9 = uilabel(app.GridLayout4);
            app.KdEditFieldLabel_9.Text = 'Kd';
            app.twoTanksPIDKd = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDKd.Limits = [0 Inf];
            app.TiempodesimulacinsLabel_2 = uilabel(app.GridLayout4);
            app.TiempodesimulacinsLabel_2.Text = 'Tiempo de simulación (s)';
            app.twoTanksPIDSimTime = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDSimTime.Limits = [0 Inf];
            app.RetrasoenaperturasLabel = uilabel(app.GridLayout4);
            app.RetrasoenaperturasLabel.Text = 'Retraso en apertura (s)';
            app.twoTanksPIDDelay = uieditfield(app.GridLayout4, 'numeric');
            app.twoTanksPIDDelay.Limits = [0 Inf];
            app.SIMULARButton = uibutton(app.GridLayout4, 'push');
            app.SIMULARButton.ButtonPushedFcn = createCallbackFcn(app, @twoTanksPIDSimButtonPushed, true);
            app.SIMULARButton.Text = 'SIMULAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tanks_modelling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end